classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        statusLamp         matlab.ui.control.Lamp
        statusLampLabel    matlab.ui.control.Label
        startGameButton    matlab.ui.control.Button
        confirmMoveButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        % here all the properties form the cheesgame get declared

        status int8     % stores the status of the game ...
                        % 0:game has not started, 1:human has to move,
                        % 2:robot is moving, 3:game has ended, -1 : error

        robot       Robot
        chessbot    Chessbot
        camera      Camera

    end
    
    methods (Access = private)
        
        function [] = statusUpdate(app, newStatus)
            % This function updates the status propertie and updates the new status on the app 
            %
            % === INPUT PARAMETERS ===
            % newStatus : id of the new status (0 ... 3)
            
            app.status = newStatus;

            switch app.status   %dependig on the status propertie the lamp and status text get changed
                case 0
                    app.statusLampLabel.Text = 'setup';
                    app.statusLamp.Color = 'green';
                case 1
                    app.statusLampLabel.Text = 'human has to move';
                    app.statusLamp.Color = 'white';
                case 2
                    app.statusLampLabel.Text = 'robot has to move';
                    app.statusLamp.Color = 'black';
                case 3
                    app.statusLampLabel.Text = 'game has endded';
                    app.statusLamp.Color = 'red';
                otherwise
                    app.statusLampLabel.Text = 'error';
                    app.statusLamp.Color = 'red';
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.statusUpdate(0);  % set status on setup mode

            app.robot = Robot();  % instatiate all the objects by calling their constructors 
            app.camera = Camera();
            app.chessbot = Chessbot();

        end

        % Button pushed function: confirmMoveButton
        function confirmMoveButtonPushed(app, event)
            
        end

        % Button pushed function: startGameButton
        function startGameButtonPushed(app, event)
            app.statusUpdate(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.confirmMoveButton = uibutton(app.UIFigure, 'push');
            app.confirmMoveButton.ButtonPushedFcn = createCallbackFcn(app, @confirmMoveButtonPushed, true);
            app.confirmMoveButton.Text = 'confirmMove';
            app.startGameButton = uibutton(app.UIFigure, 'push');
            app.startGameButton.ButtonPushedFcn = createCallbackFcn(app, @startGameButtonPushed, true);
            app.startGameButton.Text = 'startGame';
            app.statusLampLabel = uilabel(app.UIFigure);
            app.statusLampLabel.Text = 'status';
            app.statusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end